classdef dsigpro_ui_ver6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RestartButton                 matlab.ui.control.Button
        AdminModePanel                matlab.ui.container.Panel
        DecryptButton                 matlab.ui.control.Button
        IMAGESAVEDLabel_2             matlab.ui.control.Label
        WRONGPASSWORDTRYAGAINLabel    matlab.ui.control.Label
        SaveButton_2                  matlab.ui.control.Button
        RemoveNoiseButton             matlab.ui.control.Button
        DeblurButton                  matlab.ui.control.Button
        OutputImageLabel_2            matlab.ui.control.Label
        OriginalImageLabel_2          matlab.ui.control.Label
        Image_4                       matlab.ui.control.Image
        Image_3                       matlab.ui.control.Image
        SubmitButton                  matlab.ui.control.Button
        UploadDriversLicenseButton_2  matlab.ui.control.Button
        EnterAdminPasswordEditField   matlab.ui.control.EditField
        EnterAdminPasswordEditFieldLabel  matlab.ui.control.Label
        UserModePanel                 matlab.ui.container.Panel
        AESBitsDropDown               matlab.ui.control.DropDown
        AESBitsDropDownLabel          matlab.ui.control.Label
        EncryptButton                 matlab.ui.control.Button
        IMAGESAVEDLabel               matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        NoiseButton                   matlab.ui.control.Button
        BlurButton                    matlab.ui.control.Button
        OutputImageLabel              matlab.ui.control.Label
        OriginalImageLabel            matlab.ui.control.Label
        Image_2                       matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        UploadDriversLicenseButton    matlab.ui.control.Button
        SelectModePanel               matlab.ui.container.Panel
        AdminButton                   matlab.ui.control.Button
        UserButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        imgfullfile
        imagefile_user
        imagefile_admin
        imagefile_adminoriginal
        PSF
        x_double
        sk_bits
        secretKey
        iv
        imageauthTag
        encryptFlag = false
        decryptFlag = false
    end
    
    methods (Access = private)
        
        function key = generate_hex_key(~,bits)
            % Generate random hexadecimal key of specified length
            bytes = bits/8;
            key = randi([0 255], 1, bytes, 'uint8');
            key = dec2hex(key);
            key = reshape(key.', 1, []);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SelectModePanel.Enable="on";
            app.AdminModePanel.Enable="off";
            app.UserModePanel.Enable="off";
            app.WRONGPASSWORDTRYAGAINLabel.Visible="off";
            app.WRONGPASSWORDTRYAGAINLabel.Enable="off";
        end

        % Button pushed function: UploadDriversLicenseButton
        function UploadDriversLicenseButtonPushed(app, event)
            [file,path] = uigetfile('*.png','Select ID file','driverslicense.png');
            [~, name, ext] = fileparts(fullfile(path, file));
            app.imgfullfile = [name, ext];
            app.imagefile_user = imread(fullfile(path,file));
            app.Image.ImageSource = app.imagefile_user;
            app.IMAGESAVEDLabel.Visible="off";
            app.IMAGESAVEDLabel.Enable="off";
            app.Image_2.ImageSource = '';
        end

        % Button pushed function: UserButton
        function UserButtonPushed(app, event)
            app.AdminModePanel.Enable="off";
            app.UserModePanel.Enable="on";
            app.SelectModePanel.Enable="off";           
        end

        % Button pushed function: BlurButton
        function BlurButtonPushed(app, event)
             %shows ung file 
            %imagefile = imread(app.FilePathEditField.Value);
            
            % app.PSF = fspecial("gaussian",20,10);
            % blurred = imfilter(app.imagefile_user,app.PSF,"symmetric","conv");
            
            app.PSF = fspecial("motion",21,11);
            app.x_double = im2double(app.imagefile_user);
            
            %Previous Deblur
            %blurred = imfilter(app.x_double,app.PSF,"conv","circular");
            
            %8-Bit Quantization Noise Deblur
            blurred_quantized = imfilter(app.imagefile_user,app.PSF,'conv','circular');
            %

            app.Image_2.ImageSource = blurred_quantized;
            app.imagefile_user = blurred_quantized;
        end

        % Button pushed function: AdminButton
        function AdminButtonPushed(app, event)
            app.AdminModePanel.Enable="on";
            app.UserModePanel.Enable="off";
            app.SelectModePanel.Enable="off";  
            
            app.UploadDriversLicenseButton_2.Enable="off";
            app.SubmitButton.Enable="on";
            app.Image_3.Enable="off";
            app.Image_4.Enable="off";
            app.DeblurButton.Enable="off";
            app.RemoveNoiseButton.Enable="off";
            app.SaveButton_2.Enable="off";
            app.OriginalImageLabel_2.Enable="off";
            app.OutputImageLabel_2.Enable="off";
            %app.AESBitsDropDown.Enable="off";
            %app.AESBitsDropDownLabel.Enable="off";
            %app.EncryptButton.Enable="off";
            app.DecryptButton.Enable="off";

        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            passwordinput = app.EnterAdminPasswordEditField.Value;
            if passwordinput=="Admin123"
                app.SubmitButton.Enable="off";
                app.UploadDriversLicenseButton_2.Enable="on";
                app.Image_3.Enable="on";
                app.Image_4.Enable="on";
                app.DeblurButton.Enable="on";
                app.RemoveNoiseButton.Enable="on";
                app.SaveButton_2.Enable="on";
                app.OriginalImageLabel_2.Enable="on";
                app.OutputImageLabel_2.Enable="on";
                app.WRONGPASSWORDTRYAGAINLabel.Visible="off";
                app.WRONGPASSWORDTRYAGAINLabel.Enable="off";
                app.AESBitsDropDown.Enable="on";
                app.AESBitsDropDownLabel.Enable="on";
                app.EncryptButton.Enable="on";
                app.DecryptButton.Enable="on";
                app.EnterAdminPasswordEditField.Enable="off";
                app.EnterAdminPasswordEditFieldLabel.Enable="off";
            else
                app.WRONGPASSWORDTRYAGAINLabel.Visible="on";
                app.WRONGPASSWORDTRYAGAINLabel.Enable="on";
                app.EnterAdminPasswordEditField.Value = "";
            end
        end

        % Button pushed function: UploadDriversLicenseButton_2
        function UploadDriversLicenseButton_2Pushed(app, event)
            [file,path] = uigetfile('*.png','Select ID file','driverslicense.png');
            app.imagefile_admin = imread(fullfile(path,file));
            app.imagefile_adminoriginal = app.imagefile_admin;
            app.Image_3.ImageSource = app.imagefile_admin;
            app.IMAGESAVEDLabel_2.Visible="off";
            app.IMAGESAVEDLabel_2.Enable="off";
            app.Image_4.ImageSource = '';

        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            app.EnterAdminPasswordEditField.Value = "";
            app.SelectModePanel.Enable="on";
            app.AdminModePanel.Enable="off";
            app.UserModePanel.Enable="off";
            app.WRONGPASSWORDTRYAGAINLabel.Visible="off";
            app.WRONGPASSWORDTRYAGAINLabel.Enable="off";      
            app.Image.ImageSource = '';
            app.Image_2.ImageSource = '';
            app.Image_3.ImageSource = '';
            app.Image_4.ImageSource = '';
            app.IMAGESAVEDLabel.Visible="off";
            app.IMAGESAVEDLabel.Enable="off";
            app.IMAGESAVEDLabel_2.Visible="off";
            app.IMAGESAVEDLabel_2.Enable="off";
            app.EnterAdminPasswordEditField.Enable="on";
            app.EnterAdminPasswordEditFieldLabel.Enable="on";
        end

        % Button pushed function: NoiseButton
        function NoiseButtonPushed(app, event)
            %if isempty(app.NoiseButton.UserData) || app.NoiseButton.UserData == false
            %    app.NoiseButton.UserData = true;
            %end
            
            app.sk_bits = str2double(0);
            
            noise_mean = 0;
            noise_var = 0.02;
%             noisy = imnoise(app.imagefile_user,"gaussian",noise_mean,noise_var);
%             noisy = imnoise(app.imagefile_user, "speckle", 0.10);
%             noisy = imnoise(app.imagefile_user, "speckle", 0.50);
%             noisy = imnoise(app.imagefile_user, "salt & pepper", 0.5);

%             HSV = rgb2hsv(app.imagefile_admin);
%             Hue = HSV(:,:,1);
%             Hue11 = Hue;
%             Hue11(:,:,2:3) = 1;
%             Image11 = hsv2rgb(Hue11);
            
            image = app.imagefile_user;
%             display(image);
            [A,B,C] = size(image);
            if(C == 3)
                image = rgb2gray(image);
            end
            
%             display(image);
            noisy = imnoise(image,'salt & pepper', 0.1);
%             noisy_final = hsv2rgb(noisy);

            noisy_final = cat(3, noisy, noisy ,noisy);

%             display(noisy);
            display(class(noisy));

            app.Image_2.ImageSource = noisy_final;
            app.imagefile_user = noisy_final;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %if strcmp(event.Source.Tag, 'NoiseButton')
            %if app.encryptFlag == true
            if app.EncryptButton.UserData == true
                filename = sprintf('EncryptedUserID_%d.png', str2double(app.AESBitsDropDown.Value));
                app.EncryptButton.UserData = false;
            else
                filename = sprintf('EncryptedUserID_%d.png', 0);
            end
            %filename = sprintf('EncryptedUserID_%d.png', str2double(app.sk_bits));
            
            app.NoiseButton.UserData = false;

            imwrite(app.imagefile_user,filename)
            app.IMAGESAVEDLabel.Visible="on";
            app.IMAGESAVEDLabel.Enable="on";
        end

        % Button pushed function: DeblurButton
        function DeblurButtonPushed(app, event)
            %app.PSF = fspecial("gaussian",20,10);
            %unblurred = imfilter(app.imagefile_admin,app.PSF,"symmetric","conv");
            
            app.sk_bits = str2double(0);

            %app.PSF = fspecial("gaussian",20,10);
            app.PSF = fspecial("motion",21,11);
            
            %Previous Deblur
            %unblurred = deconvwnr(app.imagefile_admin, app.PSF);
            
            %8-Bit Quantization Noise Deblur
            uniform_quantization_var = (1/256)^2 / 12;
            app.x_double = im2double(app.imagefile_user);
            signal_var = var(app.x_double(:));
            NSR = uniform_quantization_var / signal_var;
            unblurred = deconvwnr(app.imagefile_admin,app.PSF,NSR);
            %

            app.Image_4.ImageSource = unblurred;
            app.imagefile_admin = unblurred;
        end

        % Button pushed function: RemoveNoiseButton
        function RemoveNoiseButtonPushed(app, event)
%             app.PSF = fspecial("gaussian",20,10);
%             noise_mean = 0;
%             noise_var = 0.02;
%             removenoise = imnoise(app.imagefile_admin,"gaussian",noise_mean,noise_var);
%             
%             NP1 = noise_var*numel(app.imagefile_adminoriginal);
%             tapered = edgetaper(removenoise,app.PSF);
%             
%             NP_scale2 = 0.9;
%             filter1 = deconvreg(tapered,app.PSF,NP1*NP_scale2);
%             
%             NP2 = noise_var*numel(NP1);
%             [filter2,lagra] = deconvreg(filter1,app.PSF,NP2);
%             
%             lagra_scale2 = 100000;
%             filter3 = deconvreg(tapered,app.PSF,[],lagra*lagra_scale2);
                
              display(class(app.imagefile_admin));
              Restored_Image = app.imagefile_admin;

              for c = 1 : 3
                  Restored_Image(:,:,c) = medfilt2(app.imagefile_admin(:,:,c), [3, 3]);
              end
%               removenoise = medfilt2(app.imagefile_admin,[5 5])
%               HSV_final = removenoise;
%               HSV_final(:,:,2:3) = 1;
%               Restored_Image = hsv2rgb(HSV_final);


%             app.Image_4.ImageSource = filter3;
%             app.imagefile_admin = filter3;

            app.Image_4.ImageSource = Restored_Image;
            app.imagefile_admin = Restored_Image;
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            if app.DecryptButton.UserData == true
                filename = sprintf('DecryptedUserID_%d.png', str2double(app.AESBitsDropDown.Value));
                app.DecryptButton.UserData = false;
            else
                filename = sprintf('DecryptedUserID_%d.png', 0);
            end
            %filename = sprintf('DecryptedUserID_%d.png', str2double(app.sk_bits));

            imwrite(app.imagefile_admin,filename)
            app.IMAGESAVEDLabel_2.Visible="on";
            app.IMAGESAVEDLabel_2.Enable="on";
        end

        % Button pushed function: EncryptButton
        function EncryptButtonPushed(app, event)
            if isempty(app.EncryptButton.UserData) || app.EncryptButton.UserData == false
                app.EncryptButton.UserData = true;
            end
            
            % simulate loading data
            d = uiprogressdlg(app.UIFigure, 'Title', 'Encrypting', 'Message', 'Please wait...');
            
            app.sk_bits = app.AESBitsDropDown.Value;

            %128, 192, or 256-bit hexadecimal
            app.secretKey = app.generate_hex_key(str2double(app.sk_bits)); 

            app.iv = dec2hex(randi([0, 255], 1, 12), 2);
            app.iv = reshape(app.iv', 1, []);

            [outputCipher,authTag] = ImageEncryption(app.secretKey, app.iv, app.imgfullfile, 'AES image');

            %app.IMAGESAVEDLabel_3.Text="KEY: " + secretKey;
            %app.IMAGESAVEDLabel_3.Visible="on";
            %app.IMAGESAVEDLabel_3.Enable="on";
            
            % pop-up window prompt
            %message = "KEY: " + secretKey;
            %msgbox(message, 'Message Title', 'modal');

            % create pop-up window
            %%f = uifigure('Name', 'Popup Window');
            %%f.Position(3:4) = [500 200];
            %%t_sk = uitextarea(f, 'Position', [50 125 400 35], 'Editable', false);
            %%t_iv = uitextarea(f, 'Position', [50 75 400 35], 'Editable', false);
        
            % set text in text box
            %%t_sk.Value = secretKey;
            %%t_iv.Value = iv;

            %%%%%
            f = uifigure('Name', 'AES Encryption');
            f.Position(3:4) = [500 175];
            
            g = uigridlayout(f, [3 2]);
            g.ColumnWidth = {'1x', '4x'};
            
            % add label and text box for Secret Key
            skLabel = uilabel(g, 'Text', 'Secret Key:');
            t_sk = uitextarea(g, 'Editable', false);
            t_sk.Value = app.secretKey;
            
            % add label and text box for IV
            ivLabel = uilabel(g, 'Text', 'IV:');
            t_iv = uitextarea(g, 'Editable', false);
            t_iv.Value = app.iv;
            
            % specify grid position of labels and text boxes
            skLabel.Layout.Row = 1;
            skLabel.Layout.Column = 1;
            t_sk.Layout.Row = 1;
            t_sk.Layout.Column = 2;
            
            ivLabel.Layout.Row = 2;
            ivLabel.Layout.Column = 1;
            t_iv.Layout.Row = 2;
            t_iv.Layout.Column = 2;
            
            % close progress dialog
            close(d);
            
            app.Image_2.ImageSource = outputCipher;
            app.imagefile_user = outputCipher;
            app.imageauthTag = authTag;
        end

        % Button pushed function: DecryptButton
        function DecryptButtonPushed(app, event)
            if isempty(app.DecryptButton.UserData) || app.DecryptButton.UserData == false
                app.DecryptButton.UserData = true;
            end
            
            % simulate loading data
            d = uiprogressdlg(app.UIFigure, 'Title', 'Decrypting', 'Message', 'Please wait...');
            
            [ImageOut,authTagResult] = ImageDecryption(app.secretKey, app.iv, app.imagefile_user, 'AES image', app.imageauthTag);
            
            % close progress dialog
            close(d);

            app.Image_4.ImageSource = ImageOut;
            app.imagefile_admin = ImageOut;
        end

        % Callback function
        function AESBitsDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectModePanel = uipanel(app.UIFigure);
            app.SelectModePanel.Title = 'Select Mode';
            app.UserButton = uibutton(app.SelectModePanel, 'push');
            app.UserButton.ButtonPushedFcn = createCallbackFcn(app, @UserButtonPushed, true);
            app.UserButton.Text = 'User';
            app.AdminButton = uibutton(app.SelectModePanel, 'push');
            app.AdminButton.ButtonPushedFcn = createCallbackFcn(app, @AdminButtonPushed, true);
            app.AdminButton.Text = 'Admin';
            app.UserModePanel = uipanel(app.UIFigure);
            app.UserModePanel.Title = 'User Mode';
            app.UploadDriversLicenseButton = uibutton(app.UserModePanel, 'push');
            app.UploadDriversLicenseButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDriversLicenseButtonPushed, true);
            app.UploadDriversLicenseButton.Text = 'Upload  Driver''s License';
            app.Image = uiimage(app.UserModePanel);
            app.Image_2 = uiimage(app.UserModePanel);
            app.OriginalImageLabel = uilabel(app.UserModePanel);
            app.OriginalImageLabel.Text = 'Original Image';
            app.OutputImageLabel = uilabel(app.UserModePanel);
            app.OutputImageLabel.Text = 'Output Image';
            app.BlurButton = uibutton(app.UserModePanel, 'push');
            app.BlurButton.ButtonPushedFcn = createCallbackFcn(app, @BlurButtonPushed, true);
            app.BlurButton.Text = 'Blur';
            app.NoiseButton = uibutton(app.UserModePanel, 'push');
            app.NoiseButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseButtonPushed, true);
            app.NoiseButton.Text = 'Noise';
            app.SaveButton = uibutton(app.UserModePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.IMAGESAVEDLabel = uilabel(app.UserModePanel);
            app.IMAGESAVEDLabel.Enable = 'off';
            app.IMAGESAVEDLabel.Visible = 'off';
            app.IMAGESAVEDLabel.Text = 'IMAGE SAVED';
            app.EncryptButton = uibutton(app.UserModePanel, 'push');
            app.EncryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptButton.Text = 'Encrypt';
            app.AESBitsDropDownLabel = uilabel(app.UserModePanel);
            app.AESBitsDropDownLabel.Text = 'AES-Bits';
            app.AESBitsDropDown = uidropdown(app.UserModePanel);
            app.AESBitsDropDown.Items = {'128', '192', '256'};
            app.AESBitsDropDown.Value = '128';
            app.AdminModePanel = uipanel(app.UIFigure);
            app.AdminModePanel.Title = 'Admin Mode';
            app.EnterAdminPasswordEditFieldLabel = uilabel(app.AdminModePanel);
            app.EnterAdminPasswordEditFieldLabel.Text = 'Enter Admin Password';
            app.EnterAdminPasswordEditField = uieditfield(app.AdminModePanel, 'text');
            app.UploadDriversLicenseButton_2 = uibutton(app.AdminModePanel, 'push');
            app.UploadDriversLicenseButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadDriversLicenseButton_2Pushed, true);
            app.UploadDriversLicenseButton_2.Text = 'Upload  Driver''s License';
            app.SubmitButton = uibutton(app.AdminModePanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.Image_3 = uiimage(app.AdminModePanel);
            app.Image_4 = uiimage(app.AdminModePanel);
            app.OriginalImageLabel_2 = uilabel(app.AdminModePanel);
            app.OriginalImageLabel_2.Text = 'Original Image';
            app.OutputImageLabel_2 = uilabel(app.AdminModePanel);
            app.OutputImageLabel_2.Text = 'Output Image';
            app.DeblurButton = uibutton(app.AdminModePanel, 'push');
            app.DeblurButton.ButtonPushedFcn = createCallbackFcn(app, @DeblurButtonPushed, true);
            app.DeblurButton.Text = 'Deblur';
            app.RemoveNoiseButton = uibutton(app.AdminModePanel, 'push');
            app.RemoveNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNoiseButtonPushed, true);
            app.RemoveNoiseButton.Text = 'Remove Noise';
            app.SaveButton_2 = uibutton(app.AdminModePanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.WRONGPASSWORDTRYAGAINLabel = uilabel(app.AdminModePanel);
            app.WRONGPASSWORDTRYAGAINLabel.Enable = 'off';
            app.WRONGPASSWORDTRYAGAINLabel.Visible = 'off';
            app.WRONGPASSWORDTRYAGAINLabel.Text = 'WRONG PASSWORD! TRY AGAIN';
            app.IMAGESAVEDLabel_2 = uilabel(app.AdminModePanel);
            app.IMAGESAVEDLabel_2.Enable = 'off';
            app.IMAGESAVEDLabel_2.Visible = 'off';
            app.IMAGESAVEDLabel_2.Text = 'IMAGE SAVED';
            app.DecryptButton = uibutton(app.AdminModePanel, 'push');
            app.DecryptButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptButtonPushed, true);
            app.DecryptButton.Text = 'Decrypt';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dsigpro_ui_ver6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end